classdef ui_draft_updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SubplotConfigurationPanel       matlab.ui.container.Panel
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        PreviewLayoutButton             matlab.ui.control.Button
        ResetSubplotConfigurationButton  matlab.ui.control.Button
        ConfigurePlotLayoutEditField    matlab.ui.control.EditField
        ConfigurePlotLayoutLabel        matlab.ui.control.Label
        SaveSubplotConfigurationButton  matlab.ui.control.Button
        UpdateDepthElectrodesButton     matlab.ui.control.Button
        UpdateSurfaceElectrodesButton   matlab.ui.control.Button
        AddDepthElectrodePlotButton     matlab.ui.control.Button
        AddSurfaceElectrodePlotButton   matlab.ui.control.Button
        DepthElectrodesLabel            matlab.ui.control.Label
        DepthElectrodesTable            matlab.ui.control.Table
        SurfaceElectrodesLabel          matlab.ui.control.Label
        SurfaceElectrodesTable          matlab.ui.control.Table
        ConfigurePlotElementsLabel      matlab.ui.control.Label
        ConfigurePlotElementsTable      matlab.ui.control.Table
        SearchExistingPatientButton     matlab.ui.control.Button
        VideoParametersPanel            matlab.ui.container.Panel
        ResetAllButton                  matlab.ui.control.Button
        RunOPSCEAButton                 matlab.ui.control.Button
        JumpToEditFieldLabel            matlab.ui.control.Label
        JumpToEditField                 matlab.ui.control.EditField
        LoadDataButton                  matlab.ui.control.Button
        DataLoadedInfoLabel             matlab.ui.control.Label
        ResetVideoParametersSeizureDataButton  matlab.ui.control.Button
        GenerateVideoPreviewButton      matlab.ui.control.Button
        SaveClipParams                  matlab.ui.control.Button
        ClipIDEditField                 matlab.ui.control.EditField
        ClipIDEditFieldLabel            matlab.ui.control.Label
        SearchExistingClipButton        matlab.ui.control.Button
        ClipIDConfigTable               matlab.ui.control.Table
    end

    properties (Access = public)
        % Initialize empty parameters that will be populated when
        % LoadDataButton is pressed
        d = [];
        LL = [];
        sfx = 0;
        ntp = 0;
        scl = 1;
        ts = [];
        jumpto = 1;
        nns = [];
        currentLoadedClip = '';

        clipParamRowNames = [
            "vidstart" 
            "vidstop" 
            "blstart" 
            "blstop"
            "llw" 
            "iceeg_scale" 
            "fps" 
            "cax" 
            "gsp"
            "cm" 
            "iceegwin" 
            "marg" 
            "slicebright" 
            "etype"
        ];

        clipParamsDefaults = struct( ...
            'vidstart', 0, ...
            'vidstop', 0, ...
            'blstart', 0, ...
            'blstop', 0, ...
            'llw', 0.25, ...
            'iceeg_scale', 97, ...
            'fps', 5, ...
            'cax', [-20 20], ...
            'gsp', 25, ...
            'cm', 'cmOPSCEAjet', ...
            'iceegwin', 5, ...
            'marg', 1, ...
            'slicebright', 25, ...
            'etype', 'clinical' ...
        );
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize plot layout
            app.ConfigurePlotLayoutEditField.Value = "6,100";
            
            % Initialize ECoG & colorbar configuration
            elementNames = {"ECoG"; "Colorbar"};
            rows = [6; 1];
            cols = [20; 5];
            tile = [81; 75];
            app.ConfigurePlotElementsTable.Data = table(elementNames, tile, rows, cols);
            app.ConfigurePlotElementsTable.Data.Properties.RowNames = ["ecog" "cb"];

            % Set empty table
            rows = [];
            cols = [];
            tile = [];
            zoom = [];
            surfaces = [];
            opacity = [];
            view = [];
            show = false(0, 0);
            remove = false(0, 0);
            app.SurfaceElectrodesTable.Data = table(tile, rows, cols, zoom, surfaces, opacity, view, show, remove);

            rows = [];
            cols = [];
            tile = [];
            zoom = [];
            labels = [];
            color = [];
            depths = [];
            remove = false(0, 0);
            app.DepthElectrodesTable.Data = table(tile, rows, cols, zoom, labels, color, depths, remove);

            % Initialize table for video clip configuration
            paramNames = {
                "Video Start"; 
                "Video Stop";
                "Baseline Start"; 
                "Baseline Stop";
                "Line Length Window (sec)"; 
                "ICEEG Scale";
                "FPS"; 
                "Color Axis";
                "GSP"; 
                "Color Map";
                "ICEEG Window"; 
                "Margin (sec)";
                "Slice Brightness"; 
                "Electrode Type"
            };

            defaultValues = {
                app.clipParamsDefaults.vidstart;
                app.clipParamsDefaults.vidstop;
                app.clipParamsDefaults.blstart; 
                app.clipParamsDefaults.blstop;
                app.clipParamsDefaults.llw; 
                app.clipParamsDefaults.iceeg_scale; 
                app.clipParamsDefaults.fps; 
                convertCharsToStrings(strjoin(arrayfun(@num2str, app.clipParamsDefaults.cax, 'UniformOutput', false), ","));
                app.clipParamsDefaults.gsp; 
                app.clipParamsDefaults.cm;
                app.clipParamsDefaults.iceegwin; 
                app.clipParamsDefaults.marg;
                app.clipParamsDefaults.slicebright; 
                app.clipParamsDefaults.etype;
            };
            app.ClipIDConfigTable.Data = table(paramNames, defaultValues);
            app.ClipIDConfigTable.Data.Properties.RowNames = app.clipParamRowNames;

            % Fix alignment in tables
            % TODO(steph): is there a better way to do this?
            s_subplot = uistyle("HorizontalAlignment", "left");
            addStyle(app.ConfigurePlotElementsTable, s_subplot, "table", "");
            addStyle(app.SurfaceElectrodesTable, s_subplot, "table", "");
            addStyle(app.DepthElectrodesTable, s_subplot, "table", "");

            s_clip = uistyle("HorizontalAlignment", "left");
            addStyle(app.ClipIDConfigTable, s_clip, "table", "");
        end

        % Callback function
        function AddElectrodeDropDownValueChanged(app, event)

        end

        % Callback function
        function AddElectrodeButtonPushed(app, event)

        end

        % Callback function
        function RemoveSelectedElectrodesButtonPushed(app, event)
            
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            % app.TabGroup.Position(1) = 1;
            % app.TabGroup.Position(2) = 1;
            % app.TabGroup.Position(4) = position(4);
        end

        % Button pushed function: SearchExistingPatientButton
        function SearchExistingPatientButtonPushed(app, event)
            patientID = app.PatientIDEditField.Value;
            filename = fullfile(getenv("KLEEN_DATA"), 'opscea', patientID, 'patient_params.mat');
            if exist(filename, "file") == 2
                patientParams = load(filename);

                %%%% Layout configuration %%%%
                app.ConfigurePlotLayoutEditField.Value = sprintf(...
                    "%d,%d",...
                    patientParams.layout.rows,...
                    patientParams.layout.cols...
                );
                    
                %%%% ECoG and colorbar configuration %%%% 
                elementNames = {"ECoG"; "Colorbar"};
                rows = [int32(patientParams.ecog.rows); int32(patientParams.cb.rows)];
                cols = [int32(patientParams.ecog.cols); int32(patientParams.cb.cols)];
                tile = [int32(patientParams.ecog.tile); int32(patientParams.cb.tile)];
                app.ConfigurePlotElementsTable.Data = table(elementNames, tile, rows, cols);
                app.ConfigurePlotElementsTable.Data.Properties.RowNames = ["ecog" "cb"];

                %%%% Surface electrodes %%%%
                % Initialize column for removing electrodes
                [numElectrodes, ~] = size(patientParams.surface);
                removeElectrodesColumn = false(numElectrodes, 1);

                % Fix display for opacity column
                opacityColumn = strings(numElectrodes, 1);
                for i=1:numElectrodes
                        opacityColumn(i) = strjoin(arrayfun(@num2str, patientParams.surface.opacity{i}, 'UniformOutput', false), ',');
                end
                
                surfaceElectrodesTable = table( ...
                    patientParams.surface.tile, ...   
                    patientParams.surface.rows, ...
                    patientParams.surface.cols, ...
                    patientParams.surface.zoom, ...
                    patientParams.surface.surfaces, ...
                    opacityColumn, ...
                    patientParams.surface.view, ...
                    patientParams.surface.show, ...
                    removeElectrodesColumn...
                  );
                app.SurfaceElectrodesTable.Data = surfaceElectrodesTable;
                app.SurfaceElectrodesTable.Data.Properties.VariableNames = [
                    "tile", "rows", "cols", "zoom", "surfaces", "opacity", "view", "show", "remove"
                ];

                %%%% Depth electrodes %%%%
                % Initialize column for removing electrodes
                [numElectrodes, ~] = size(patientParams.depth);
                removeElectrodesColumn = false(numElectrodes, 1);
                
                % Fix display for color column
                colorColumn = strings(numElectrodes, 1);
                for i=1:numElectrodes
                    colorColumn(i) = strjoin(arrayfun(@num2str, transpose(patientParams.depth.color(i, :)), 'UniformOutput', false), ',');
                end
                
                % Fix display for depths column
                depthColumn = strings(numElectrodes, 1);
                for i=1:numElectrodes
                    % depthColumn(i) = strjoin(string(patientParams.depth.depths{i}), ',');
                    depth_i = patientParams.depth.depths{i};
                    depthColumn(i) = string(depth_i(1)) + ':' + string(depth_i(end));
                end

                depthElectrodesTable = table( ...
                    patientParams.depth.tile, ...
                    patientParams.depth.rows, ...
                    patientParams.depth.cols, ...
                    patientParams.depth.zoom, ...
                    patientParams.depth.labels, ...
                    colorColumn, ...
                    depthColumn, ...
                    removeElectrodesColumn...
                  );
                app.DepthElectrodesTable.Data = depthElectrodesTable;
                app.DepthElectrodesTable.Data.Properties.VariableNames = [
                    "tile", "rows", "cols", "zoom", "labels", "color", "depths", "remove"
                ];
            else
                msgbox(sprintf("Subplot params for patient %s not found. Please create a new configuration.", patientID));
            end
        end

        % Button pushed function: AddSurfaceElectrodePlotButton
        function AddSurfaceElectrodePlotButtonPushed(app, event)
            newRow = cell2table({0, 0, 0, 0, '', '', '', false, false});
            newRow.Properties.VariableNames = [
                "tile", "rows", "cols", "zoom", "surfaces", "opacity", "view", "show", "remove"
            ];
            app.SurfaceElectrodesTable.Data = [app.SurfaceElectrodesTable.Data; newRow];
        end

        % Button pushed function: UpdateDepthElectrodesButton
        function UpdateDepthElectrodesButtonPushed(app, event)
            [numElectrodes, numColumns] = size(app.DepthElectrodesTable.Data);
            electrodesToRemove = [];
            for i=1:numElectrodes
                % RemoveElectrode column is always the last column
                if app.DepthElectrodesTable.Data{i, numColumns} == true
                    electrodesToRemove = [electrodesToRemove; i];
                end
            end
            
            app.DepthElectrodesTable.Data(electrodesToRemove, :) = [];
        end

        % Button pushed function: AddDepthElectrodePlotButton
        function AddDepthElectrodePlotButtonPushed(app, event)
            newRow = cell2table({0, 0, 0, 0, '', '', '', false(1, 1)});
            newRow.Properties.VariableNames = [
                "tile", "rows", "cols", "zoom", "labels", "color", "depths", "remove"
            ];
            app.DepthElectrodesTable.Data = [app.DepthElectrodesTable.Data; newRow];
        end

        % Button pushed function: UpdateSurfaceElectrodesButton
        function UpdateSurfaceElectrodesButtonPushed(app, event)
            [numElectrodes, numColumns] = size(app.SurfaceElectrodesTable.Data);
            electrodesToRemove = [];
            for i=1:numElectrodes
                % RemoveElectrode column is always the last column
                if app.SurfaceElectrodesTable.Data{i, numColumns} == true
                    electrodesToRemove = [electrodesToRemove; i];
                end
            end
            
            app.SurfaceElectrodesTable.Data(electrodesToRemove, :) = [];
        end

        % Button pushed function: SearchExistingClipButton
        function SearchExistingClipButtonPushed(app, event)
            patientID = app.PatientIDEditField.Value;
            
            % Check that patientID is not empty
            if strrep(patientID, " ", "") == ""
                msgbox(sprintf("Please specify a patient ID"));
                return;
            end

            % Check that patient ID is valid (e.g. a folder has been
            % created for this patient). TODO(steph): is this needed? 
            % Should we always guarantee that valid patients will have
            % an existing folder (that may or may not be empty?)
            patientFolder = fullfile(getenv("KLEEN_DATA"), 'opscea', patientID);
            if exist(patientFolder, "dir") ~= 7
                msgbox(sprintf("Patient %s not found. Please specify another patient or create a new subplot configuration for this patient.", patientID));
                return;
            end
            
            clipID = app.ClipIDEditField.Value;
            filename = fullfile(patientFolder, [char(patientID) '_' char(clipID)], [char(patientID) '_' char(clipID) '_params.mat']);
            % filename = fullfile(patientFolder, [char(patientID) '_' char(clipID)], 'clip_params.mat');

            if exist(filename, "file") == 2
                clipParams = load(filename);
                paramNames = {
                    "Video Start"; 
                    "Video Stop";
                    "Baseline Start"; 
                    "Baseline Stop";
                    "Line Length Window (sec)"; 
                    "ICEEG Scale";
                    "FPS"; 
                    "Color Axis";
                    "GSP"; 
                    "Color Map";
                    "ICEEG Window"; 
                    "Margin (sec)";
                    "Slice Brightness"; 
                    "Electrode Type"
                };

                % Check that certain parameters exist
                if ~isfield(clipParams, 'vidstart') || ~isfield(clipParams, 'vidstop') || ~isfield(clipParams, 'blstart') || ~isfield(clipParams, 'blstop')
                    % Display a message box if 'vidstart' is missing
                    msgbox("Missing one or more of the following fields in the clip configuration: vidstart, vidstop, blstart, blstart." + newline + newline + "Please use EEGHILITE to save these parameters.");
                    return;
                end

                loadedValues = {
                    % These parameters should have been set using EEGHILITE
                    clipParams.vidstart; 
                    clipParams.vidstop;
                    clipParams.blstart; 
                    clipParams.blstop;
                    % These values may or may not be set
                    getFieldOrDefault(clipParams, 'llw', app.clipParamsDefaults.llw); 
                    getFieldOrDefault(clipParams, 'iceeg_scale', app.clipParamsDefaults.iceeg_scale);
                    getFieldOrDefault(clipParams, 'fps', app.clipParamsDefaults.fps); 
                    convertCharsToStrings(strjoin(arrayfun(@num2str, getFieldOrDefault(clipParams, 'cax', app.clipParamsDefaults.cax), 'UniformOutput', false), ","));
                    getFieldOrDefault(clipParams, 'gsp', app.clipParamsDefaults.gsp); 
                    getFieldOrDefault(clipParams, 'cm', app.clipParamsDefaults.cm);
                    getFieldOrDefault(clipParams, 'iceegwin', app.clipParamsDefaults.iceegwin); 
                    getFieldOrDefault(clipParams, 'marg', app.clipParamsDefaults.marg);
                    getFieldOrDefault(clipParams, 'slicebright', app.clipParamsDefaults.slicebright); 
                    getFieldOrDefault(clipParams, 'etype', app.clipParamsDefaults.etype)
                };

                app.ClipIDConfigTable.Data = table(paramNames, loadedValues);
                app.ClipIDConfigTable.Data.Properties.RowNames = app.clipParamRowNames;
            else
                msgbox(sprintf("Clip %s for patient %s not found. Please create a new configuration.", clipID, patientID));
            end
        end

        % Button pushed function: SaveSubplotConfigurationButton
        function SaveSubplotConfigurationButtonPushed(app, event)
            % Validate patientID
            patientID = app.PatientIDEditField.Value;
            folderPath = fullfile(getenv("KLEEN_DATA"), 'opscea', patientID);
            if exist(folderPath, "dir") ~= 7
                mkdir(folderPath)
            end

            % Patient params: layout
            splitStr = split(app.ConfigurePlotLayoutEditField.Value, ",");
            layout.rows = str2double(splitStr(1));
            layout.cols = str2double(splitStr(2));

            % Patient params: ecog
            ecogFields = app.ConfigurePlotElementsTable.Data{"ecog", 2:4};
            rows = double(ecogFields(2));
            cols = double(ecogFields(3));
            tile = double(ecogFields(1));
            ecog = table(rows, cols, tile);

            % Patient params: cb 
            cbFields = app.ConfigurePlotElementsTable.Data{"cb", 2:4};
            rows = double(cbFields(2));
            cols = double(cbFields(3));
            tile = double(cbFields(1));
            cb = table(rows, cols, tile);

            % Patient params: surface electrodes
            surfaceElectrodesTable = app.SurfaceElectrodesTable.Data;
            tile = double(surfaceElectrodesTable.tile);
            rows = double(surfaceElectrodesTable.rows);
            cols = double(surfaceElectrodesTable.cols);
            zoom = double(surfaceElectrodesTable.zoom);
            surfaces = surfaceElectrodesTable.surfaces;
            view = surfaceElectrodesTable.view;
            show = surfaceElectrodesTable.show;

            % Need to convert comma-delimited string to array
            [numRows, ~] = size(app.SurfaceElectrodesTable.Data);
            opacity = cell(numRows, 1);
            for i=1:numRows
                opacity{i, 1} = transpose(double(split(surfaceElectrodesTable.opacity(i), ",")));
            end
            
            surface = table(zoom, surfaces, opacity, view, show, rows, cols, tile);

            % Patient params: depth electrodes
            depthElectrodesTable = app.DepthElectrodesTable.Data;
            tile = double(depthElectrodesTable.tile);
            rows = double(depthElectrodesTable.rows);
            cols = double(depthElectrodesTable.cols);
            zoom = double(depthElectrodesTable.zoom);
            labels = depthElectrodesTable.labels;

            % Need to convert comma-delimited string to array
            [numRows, ~] = size(app.DepthElectrodesTable.Data);
            depths = cell(numRows, 1);
            color = cell(numRows, 3);
            for i=1:numRows
                % DEPRECATED: assumes depthElectrodesTable.depths(i) is a
                % comma-delimited list start,start+1,...,end
                % depths{i, 1} = transpose(double(split(depthElectrodesTable.depths(i), ",")));

                % Assumes depthElectrodesTable.depths(i) has the format start:end
                depths{i, 1} = eval(depthElectrodesTable.depths(i));
                color(i, :) = num2cell(double(split(depthElectrodesTable.color(i), ",")));
            end
            color = double(string(color));

            depth = table(zoom, labels, color, depths, rows, cols, tile);

            % Save data
            filename = sprintf("%s/patient_params.mat", folderPath);
            save(filename, 'layout', 'ecog', 'cb', 'surface', 'depth');
            
            msgbox(sprintf("Saved configuration for patient %s", patientID));
        end

        % Button pushed function: SaveClipParams
        function SaveClipParamsButtonPushed(app, event)
            patientID = app.PatientIDEditField.Value;
            
            % Check that patientID is not empty
            if strrep(patientID, " ", "") == ""
                msgbox(sprintf("Please specify a patient ID"));
                return;
            end

            % Check that patient ID is valid (e.g. a folder has been
            % created for this patient). TODO(steph): is this needed? 
            % Should we always guarantee that valid patients will have
            % an existing folder (that may or may not be empty?)
            patientFolder = fullfile(getenv("KLEEN_DATA"), 'opscea', patientID);
            if exist(patientFolder, "dir") ~= 7
                msgbox(sprintf("Patient %s not found. Please specify another patient or create a new subplot configuration for this patient.", patientID));
                return;
            end

            % Get list of folders for existing clips
            fileList = dir(patientFolder);
            folders = {fileList([fileList.isdir]).name};
            foldersToCheck = folders(startsWith(folders, patientID));
            idsOnly = strings(length(foldersToCheck));
            for i=1:length(foldersToCheck)
                idsOnly(i) = strrep(foldersToCheck(i), sprintf("%s_", patientID), "");
            end

            clipID = strrep(app.ClipIDEditField.Value, " ", "");
            if clipID == ""
                % If empty clip ID, set it to be the next highest number
                numericalIdsOnly = double(idsOnly);
                numericalIdsOnly = numericalIdsOnly(~isnan(numericalIdsOnly));
                if isempty(numericalIdsOnly)
                    maxID = 0;
                else
                    maxID = double(max(numericalIdsOnly));
                end

                finalClipID = maxID + 1;

                % Format with leading 0s
                if finalClipID < 10
                    finalClipID = sprintf("0%d", finalClipID);
                else
                    finalClipID = string(finalClipID);
                end

            elseif ismember(clipID, idsOnly)
                % Check that user intends to overwrite/update existing data
                message = sprintf("Video params with clip ID %s exists for patient %s. Do you want to overwrite the existing data?", clipID, patientID);
                btn1 = "Overwrite";
                btn2 = "Cancel";
                choice = questdlg(message, "Warning", btn1, btn2, btn2);
                if choice == "Cancel"
                    msgbox("Configuration not saved.");
                    return;
                else
                    finalClipID = clipID;
                end
            
            else
                finalClipID = clipID;
            end
            
            vidstart = double(app.ClipIDConfigTable.Data{"vidstart", 2}{1});
            vidstop = double(app.ClipIDConfigTable.Data{"vidstop", 2}{1});
            blstart = double(app.ClipIDConfigTable.Data{"blstart", 2}{1});
            blstop = double(app.ClipIDConfigTable.Data{"blstop", 2}{1});
            llw = double(app.ClipIDConfigTable.Data{"llw", 2}{1});
            iceeg_scale = double(app.ClipIDConfigTable.Data{"iceeg_scale", 2}{1});
            fps = double(app.ClipIDConfigTable.Data{"fps", 2}{1});
            cax = double(split(app.ClipIDConfigTable.Data{"cax", 2}{1}, ","));
            gsp = double(app.ClipIDConfigTable.Data{"gsp", 2}{1});
            cm = app.ClipIDConfigTable.Data{"cm", 2}{1};
            iceegwin = double(app.ClipIDConfigTable.Data{"iceegwin", 2}{1});
            marg = double(app.ClipIDConfigTable.Data{"marg", 2}{1});
            slicebright = double(app.ClipIDConfigTable.Data{"slicebright", 2}{1});
            etype = app.ClipIDConfigTable.Data{"etype", 2}{1};
            patient = patientID;
            clipid = finalClipID;
            
            patientClipFolder = sprintf("%s_%s", patientID, finalClipID);
            folderPath = sprintf("%s/%s", patientFolder, patientClipFolder);
            if exist(folderPath, "dir") ~= 7
                mkdir(folderPath)
            end
            
            filename = sprintf("%s/%s_params.mat", folderPath, patientClipFolder);
            save(filename, "vidstart", "vidstop", "blstart", "blstop", "llw", "iceeg_scale",...
                "fps", "cax", "gsp", "cm", "iceegwin", "marg", "slicebright", "etype",...
                "patient", "clipid");

            message = sprintf("Video params for clip ID %s saved for patient %s", finalClipID, patientID);
            msgbox(message);
        end

        % Button pushed function: GenerateVideoPreviewButton
        function GenerateVideoPreviewButtonButtonPushed(app, event)
            pt = app.PatientIDEditField.Value;
            sz = app.ClipIDEditField.Value;
            ptsz = [pt '_' sz]; % prefix for filenames of specific seizure
        
            % Check if the seizure data that has been loaded matches
            % this patient and clip
            if ~strcmp(app.currentLoadedClip, ptsz) || isempty(app.d)
               msgbox(sprintf("Data for patient %s with clip ID %s has not yet been loaded. Skipping preview generation.", pt, sz)); 
               return
            end

            datapath = getenv('KLEEN_DATA');
            opsceapath = fullfile(datapath, 'opscea'); %path for parameters sheet
            imagingpath = fullfile(datapath, 'imaging');
            
            ptpath = fullfile(opsceapath, pt); % patient's folder
            ptparams = fullfile(ptpath, 'patient_params.mat');
            % At this point, patient_params.mat should have been generated,
            % so params should be read from the saved file
            if exist(ptparams, 'file') ~= 2
                msgbox(sprintf("patient_params.mat does not exist for patient %s. Please use 'Subplot Configuration' to save out these parameters.", pt));
                return
            end

            load_patient_params(ptparams);
            load_clip_params('', false, app);

            loadingbox = uiprogressdlg(app.UIFigure, 'Title', 'Loading video preview...', 'Indeterminate', 'on');

            % bgc = app.GenerateVideoPreviewButton.BackgroundColor;
            % tc = app.GenerateVideoPreviewButton.FontColor;
            % app.GenerateVideoPreviewButton.BackgroundColor = [0.8 0.8 0.8];
            % app.GenerateVideoPreviewButton.Text = 'Loading...';
            % app.GenerateVideoPreviewButton.FontColor = [0.5 0.5 0.5];
        
            [eleclabels, em, ~, depthch, axislim] = load_prepare_imaging(pt, imagingpath);
            [ytl, nch, chanorder] = prepare_plotting(eleclabels, app.nns, app.d, true);
            
            global S;
            S.maxbased = true;
            app.jumpto = str2double(app.JumpToEditField.Value) * app.sfx + 1;
            % Make sure that jumpto is not greater than the lenth of
            % the video
            if app.jumpto > app.ntp-app.sfx*S.iceegwin
                message = sprintf("Value %s exceeds length of video timeframe. Setting preview to first second of video.", app.JumpToEditField.Value);
                msgbox(message);
                app.jumpto = app.sfx + 1;
            end
            
            frametimpoints = app.jumpto:S.fram:app.ntp-app.sfx*S.iceegwin;
            i  = frametimpoints(1);

            % 9/14/2025- I added these parts because it wasn't updating after changing the value in the UI. Seems like we should always have it pull from the UI fields like this for all variables, a future change to tackle.-JK
            prctl=double(app.ClipIDConfigTable.Data{"iceeg_scale", 2}{1});
            app.scl = 2/diff(prctile(reshape(app.d,1,numel(app.d)),[100-prctl prctl]));
            S.etype=app.ClipIDConfigTable.Data{"etype", 2}; 
            S.etype=S.etype{1};
            
            figure('color', 'w', 'Position', [1 5 1280 700], 'Name', 'Preview', 'NumberTitle', 'off');
            %figure('color', 'w', 'Position', [1 5 1511 861], 'Name', 'Preview', 'NumberTitle', 'off');
            plot_frame( ...
                i, true, app.LL, app.d, ...
                app.sfx, nch, app.nns, app.scl, app.ts, ...
                ytl, chanorder, true, pt, em, depthch, ...
                axislim, datapath, S.maxbased ...
            );

            % app.GenerateVideoPreviewButton.BackgroundColor = bgc;
            % app.GenerateVideoPreviewButton.Text = 'Preview';
            % app.GenerateVideoPreviewButton.FontColor = tc;
            
            % Give an extra second before closing the box
            pause('on');
            pause(2);
            pause('off');
            close(loadingbox);
            
        end

        % Button pushed function: ResetSubplotConfigurationButton
        function ResetSubplotConfigurationButtonPushed(app, event)
            % Check that user intends to reset form
            message = "Reset subplot configuration to defaults?";
            btn1 = "Yes";
            btn2 = "No";
            choice = questdlg(message, "Warning", btn1, btn2, btn2);
            if choice == "No"
                return;
            end

            reset_subplot_configuration(app);
        end

        % Button pushed function: ResetVideoParametersSeizureDataButton
        function ResetVideoParametersSeizureDataButtonPushed(app, event)
            % Check that user intends to reset form
            message = "Reset video parameters to defaults and clear loaded seizure data?";
            btn1 = "Yes";
            btn2 = "No";
            choice = questdlg(message, "Warning", btn1, btn2, btn2);
            if choice == "No"
                return;
            end
            
            reset_video_parameters(app);
            reset_loaded_seizure_data(app);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            pt = app.PatientIDEditField.Value;
            sz = app.ClipIDEditField.Value;
            ptsz = [pt '_' sz];
            
            % Check if seizure data has already been loaded
            if strcmp(app.currentLoadedClip, ptsz) && ~isempty(app.d)
               return 
            end
            
            datapath = getenv('KLEEN_DATA');
            opsceapath = fullfile(datapath, 'opscea'); %path for parameters sheet
            imagingpath = fullfile(datapath, 'imaging');
            
            ptpath = fullfile(opsceapath, pt); % patient's folder
            szpath = fullfile(ptpath, ptsz); % specific seizure's folder
            ptparams = fullfile(ptpath, 'patient_params.mat');
            % At this point, patient_params.mat should have been generated,
            % so params should be read from the saved file
            if exist(ptparams, 'file') ~= 2
                msgbox(sprintf("patient_params.mat does not exist for patient %s. Please use 'Subplot Configuration' to save out these parameters.", pt));
                return
            end

            clipparams = fullfile(szpath, [ptsz '_params.mat']);
            % clipparams = fullfile(szpath, 'clip_params.mat');
            % Check that seizure exists
            if exist(szpath, 'dir') ~= 7
                msgbox(sprintf("Seizure data with clip ID %s for patient %s doesn't exist. Please specify another clip ID.", sz, pt));
                return;
            end

            loadingbox = uiprogressdlg(app.UIFigure, 'Title', 'Loading seizure data...', 'Indeterminate', 'on');
            load_patient_params(ptparams);
            load_clip_params(clipparams, false, app);
            
            [~, em, ~, ~, ~] = load_prepare_imaging(pt, imagingpath);
            [app.d, app.sfx, app.LL, app.ntp, ~, app.scl, app.ts, app.nns] = load_prepare_clip(pt, szpath, ptsz, app.jumpto, em);
            
            % Update currentLoadedClip to the patient seizure that was just loaded
            app.currentLoadedClip = ptsz;
            
            close(loadingbox);

            app.DataLoadedInfoLabel.Text = sprintf("Seizure data currently loaded for: patient %s with clip ID %s", pt, sz);
        end

        % Button pushed function: PreviewLayoutButton
        function PreviewLayoutButtonPushed(app, event)
            pt = app.PatientIDEditField.Value;
            if isempty(pt)
                msgbox("Please specify a patient ID");
                return;
            end
            
            datapath = getenv('KLEEN_DATA');
            imagingpath = fullfile(datapath, 'imaging');

            loadingbox = uiprogressdlg(app.UIFigure, 'Title', 'Loading subplot preview...', 'Indeterminate', 'on');

            % bgc = app.PreviewLayoutButton.BackgroundColor;
            % tc = app.PreviewLayoutButton.FontColor;
            % app.PreviewLayoutButton.BackgroundColor = [0.8 0.8 0.8];
            % app.PreviewLayoutButton.Text = 'Loading...';
            % app.PreviewLayoutButton.FontColor = [0.5 0.5 0.5];
            
            load_patient_params('', app);

            % This is similar to GenerateVideoPreviewButtonPushed but it 
            % assumes that no data has been loaded. Even if data is
            % loaded, it will not plot any heatmaps because it does not
            % rely on the ClipID being set
            [eleclabels, em, nnsDefault, depthch, axislim] = load_prepare_imaging(pt, imagingpath);
            [ytl, nch, chanorder] = prepare_plotting(eleclabels, nnsDefault, [], true);
            
            % Need to set these variables for colorbar
            global S;
            S.cax = [-20 20];
            S.cm = cmOPSCEAjet;
            S.gsp = 25;
            S.sliceplane = 'c';
            S.maxbased = true;
              
            figure('color', 'w', 'Position', [1 5 1280 700], 'Name', 'Preview', 'NumberTitle', 'off');
            plot_frame( ...
                0, true, [], [], 0, nch, nnsDefault, 1, [], ...
                ytl, chanorder, true, pt, em, depthch, axislim, datapath, S.maxbased ...
            );

            % app.PreviewLayoutButton.BackgroundColor = bgc;
            % app.PreviewLayoutButton.Text = 'Preview Subplot Layout';
            % app.PreviewLayoutButton.FontColor = tc;

            % Give an extra second before closing the box
            pause('on');
            pause(2);
            pause('off');
            close(loadingbox);
        end

        % Button pushed function: RunOPSCEAButton
        function RunOPSCEAButtonPushed(app, event)
            pt = app.PatientIDEditField.Value;
            sz = app.ClipIDEditField.Value;
            global S
            OPSCEA(pt, sz, true, 0, false, S.maxbased);
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            % Check that user intends to reset form
            message = "Reset all values to defaults? This includes the subplot configuration, video parameters, and loaded seizure data.";
            btn1 = "Yes";
            btn2 = "No";
            choice = questdlg(message, "Warning", btn1, btn2, btn2);
            if choice == "No"
                return;
            end
            
            reset_subplot_configuration(app);
            reset_video_parameters(app);
            reset_loaded_seizure_data(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.VideoParametersPanel = uipanel(app.UIFigure);
            app.VideoParametersPanel.Title = 'Video Parameters';
            app.ClipIDConfigTable = uitable(app.VideoParametersPanel);
            app.ClipIDConfigTable.ColumnName = {'Parameter'; 'Value'};
            app.ClipIDConfigTable.RowName = {};
            app.ClipIDConfigTable.ColumnEditable = [false true];
            app.SearchExistingClipButton = uibutton(app.VideoParametersPanel, 'push');
            app.SearchExistingClipButton.ButtonPushedFcn = createCallbackFcn(app, @SearchExistingClipButtonPushed, true);
            app.SearchExistingClipButton.Text = 'Search Existing Clip';
            app.ClipIDEditFieldLabel = uilabel(app.VideoParametersPanel);
            app.ClipIDEditFieldLabel.Text = 'Clip ID';
            app.ClipIDEditField = uieditfield(app.VideoParametersPanel, 'text');
            app.SaveClipParams = uibutton(app.VideoParametersPanel, 'push');
            app.SaveClipParams.ButtonPushedFcn = createCallbackFcn(app, @SaveClipParamsButtonPushed, true);
            app.SaveClipParams.Text = 'Save Video Parameters';
            app.GenerateVideoPreviewButton = uibutton(app.VideoParametersPanel, 'push');
            app.GenerateVideoPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateVideoPreviewButtonButtonPushed, true);
            app.GenerateVideoPreviewButton.Text = 'Preview';
            app.ResetVideoParametersSeizureDataButton = uibutton(app.VideoParametersPanel, 'push');
            app.ResetVideoParametersSeizureDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetVideoParametersSeizureDataButtonPushed, true);
            app.ResetVideoParametersSeizureDataButton.Text = 'Reset Video Parameters & Seizure Data';
            app.DataLoadedInfoLabel = uilabel(app.VideoParametersPanel);
            app.DataLoadedInfoLabel.Text = 'Seizure data currently loaded for: None';
            app.LoadDataButton = uibutton(app.VideoParametersPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Seizure Data';
            app.JumpToEditField = uieditfield(app.VideoParametersPanel, 'text');
            app.JumpToEditField.Value = '1.0';
            app.JumpToEditFieldLabel = uilabel(app.VideoParametersPanel);
            app.JumpToEditFieldLabel.Text = 'Preview video at timepoint (sec):';
            app.RunOPSCEAButton = uibutton(app.VideoParametersPanel, 'push');
            app.RunOPSCEAButton.ButtonPushedFcn = createCallbackFcn(app, @RunOPSCEAButtonPushed, true);
            app.RunOPSCEAButton.Text = 'Run OPSCEA';
            app.ResetAllButton = uibutton(app.VideoParametersPanel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.SubplotConfigurationPanel = uipanel(app.UIFigure);
            app.SubplotConfigurationPanel.Title = 'Subplot Configuration';
            app.SearchExistingPatientButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.SearchExistingPatientButton.ButtonPushedFcn = createCallbackFcn(app, @SearchExistingPatientButtonPushed, true);
            app.SearchExistingPatientButton.Text = 'Search Existing Patient';
            app.ConfigurePlotElementsTable = uitable(app.SubplotConfigurationPanel);
            app.ConfigurePlotElementsTable.ColumnName = {'Element'; 'Tile'; 'Number of Rows'; 'Number of Columns'};
            app.ConfigurePlotElementsTable.RowName = {};
            app.ConfigurePlotElementsTable.ColumnEditable = [false true true true];
            app.ConfigurePlotElementsLabel = uilabel(app.SubplotConfigurationPanel);
            app.ConfigurePlotElementsLabel.Text = 'Configure Plot Elements';
            app.SurfaceElectrodesTable = uitable(app.SubplotConfigurationPanel);
            app.SurfaceElectrodesTable.ColumnName = {'Tile'; 'Number of Rows'; 'Number of Columns'; 'Zoom'; 'Surfaces'; 'Opacity'; 'View'; 'Show'; 'Remove Suplot'};
            app.SurfaceElectrodesTable.RowName = {};
            app.SurfaceElectrodesTable.ColumnEditable = true;
            app.SurfaceElectrodesLabel = uilabel(app.SubplotConfigurationPanel);
            app.SurfaceElectrodesLabel.Text = 'Surface Electrodes';
            app.DepthElectrodesTable = uitable(app.SubplotConfigurationPanel);
            app.DepthElectrodesTable.ColumnName = {'Tile'; 'Number of Rows'; 'Number of Columns'; 'Zoom'; 'Labels'; 'Color'; 'Depths'; 'Remove Suplot'};
            app.DepthElectrodesTable.RowName = {};
            app.DepthElectrodesTable.ColumnEditable = true;
            app.DepthElectrodesLabel = uilabel(app.SubplotConfigurationPanel);
            app.DepthElectrodesLabel.Text = 'Depth Electrodes';
            app.AddSurfaceElectrodePlotButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.AddSurfaceElectrodePlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddSurfaceElectrodePlotButtonPushed, true);
            app.AddSurfaceElectrodePlotButton.Text = 'Add Electrode Plot';
            app.AddDepthElectrodePlotButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.AddDepthElectrodePlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddDepthElectrodePlotButtonPushed, true);
            app.AddDepthElectrodePlotButton.Text = 'Add Electrode Plot';
            app.UpdateSurfaceElectrodesButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.UpdateSurfaceElectrodesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSurfaceElectrodesButtonPushed, true);
            app.UpdateSurfaceElectrodesButton.Text = 'Update Config';
            app.UpdateDepthElectrodesButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.UpdateDepthElectrodesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDepthElectrodesButtonPushed, true);
            app.UpdateDepthElectrodesButton.Text = 'Update Config';
            app.SaveSubplotConfigurationButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.SaveSubplotConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSubplotConfigurationButtonPushed, true);
            app.SaveSubplotConfigurationButton.Text = 'Save Subplot Configuration';
            app.ConfigurePlotLayoutLabel = uilabel(app.SubplotConfigurationPanel);
            app.ConfigurePlotLayoutLabel.Text = 'Plot Layout';
            app.ConfigurePlotLayoutEditField = uieditfield(app.SubplotConfigurationPanel, 'text');
            app.ConfigurePlotLayoutEditField.Placeholder = 'rows,columns';
            app.ResetSubplotConfigurationButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.ResetSubplotConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSubplotConfigurationButtonPushed, true);
            app.ResetSubplotConfigurationButton.Text = 'Reset Subplot Configuration';
            app.PreviewLayoutButton = uibutton(app.SubplotConfigurationPanel, 'push');
            app.PreviewLayoutButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewLayoutButtonPushed, true);
            app.PreviewLayoutButton.Text = 'Preview Subplot Layout';
            app.PatientIDEditFieldLabel = uilabel(app.SubplotConfigurationPanel);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.SubplotConfigurationPanel, 'text');
            app.PatientIDEditField.Placeholder = 'EC000';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_draft_updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end